classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThermoProUIFigure             matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        PauseButton                   matlab.ui.control.Button
        ChargerJSONButton             matlab.ui.control.Button
        LocalisationdesthermistancesPanel  matlab.ui.container.Panel
        T3xyEditField_2               matlab.ui.control.NumericEditField
        T2xyEditField_4               matlab.ui.control.NumericEditField
        T1xyEditField_2               matlab.ui.control.NumericEditField
        T3xyEditField                 matlab.ui.control.NumericEditField
        T3xyEditFieldLabel            matlab.ui.control.Label
        T2xyEditField                 matlab.ui.control.NumericEditField
        T2xyEditFieldLabel            matlab.ui.control.Label
        T1xyEditField                 matlab.ui.control.NumericEditField
        T1xyEditFieldLabel            matlab.ui.control.Label
        TempratureambianteEditField   matlab.ui.control.NumericEditField
        TempratureambianteEditFieldLabel  matlab.ui.control.Label
        TempratureinitialeplaqueEditField  matlab.ui.control.NumericEditField
        TempratureinitialeplaqueEditFieldLabel  matlab.ui.control.Label
        ParamtrespardfautButton       matlab.ui.control.Button
        ChargerparamtresButton        matlab.ui.control.Button
        DuresEditField                matlab.ui.control.NumericEditField
        DuresEditFieldLabel           matlab.ui.control.Label
        ConductivitWmKEditField       matlab.ui.control.NumericEditField
        ConductivitWmKEditFieldLabel  matlab.ui.control.Label
        rhoField                      matlab.ui.control.NumericEditField
        MasseVolumiquegcm3EditFieldLabel  matlab.ui.control.Label
        stopButton                    matlab.ui.control.Button
        LargeurmmEditFieldLabel       matlab.ui.control.Label
        startButton                   matlab.ui.control.Button
        PerturbationPanel             matlab.ui.container.Panel
        Switch                        matlab.ui.control.Switch
        PositionxminxmaxEditField_4   matlab.ui.control.NumericEditField
        PositionxminxmaxEditField_4Label  matlab.ui.control.Label
        EditField_5                   matlab.ui.control.NumericEditField
        PositionxminxmaxEditField_3   matlab.ui.control.NumericEditField
        EditField_4                   matlab.ui.control.NumericEditField
        PositionxminxmaxEditField_2Label_2  matlab.ui.control.Label
        TensionEditField              matlab.ui.control.NumericEditField
        TensionEditFieldLabel         matlab.ui.control.Label
        TensionauTECPanel             matlab.ui.container.Panel
        Switch_2                      matlab.ui.control.Switch
        PositionxminxmaxEditField_6   matlab.ui.control.NumericEditField
        PositionxminxmaxEditField_6Label  matlab.ui.control.Label
        TensionEditField_2            matlab.ui.control.NumericEditField
        TensionEditField_2Label       matlab.ui.control.Label
        EditField_7                   matlab.ui.control.NumericEditField
        PositionxminxmaxEditField_5   matlab.ui.control.NumericEditField
        EditField_6                   matlab.ui.control.NumericEditField
        PositionxminxmaxEditField_2Label_3  matlab.ui.control.Label
        LargeurmmEditField            matlab.ui.control.NumericEditField
        TempratureinitialeCEditField  matlab.ui.control.NumericEditField
        TempratureinitialeCEditFieldLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        hField                        matlab.ui.control.NumericEditField
        CoefficientdeConvectionWm2KEditFieldLabel  matlab.ui.control.Label
        cpField                       matlab.ui.control.NumericEditField
        CapacitCalorifiqueJkgKEditFieldLabel  matlab.ui.control.Label
        paisseurmmEditField           matlab.ui.control.NumericEditField
        paisseurmmEditFieldLabel      matlab.ui.control.Label
        LongueurmmEditField           matlab.ui.control.NumericEditField
        LongueurmmEditFieldLabel      matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
     TempsTotal ;  % Durée de la simulation [s]
        Lx    ;       % Longueur [m]
        Ly    ;       % Largeur [m]
        epaisseur;    % Épaisseur [m]
        Nx        ;   % Nombre d'éléments en x
        Ny ;          % Nombre d'éléments en y
        k  ;        % Conductivité thermique [W/m·K]
        rho  ;       % Densité [kg/m^3]
        cp  ;        % Chaleur spécifique [J/kg·K]
        %materiau   ; % Nom du matériau
        alpha     ;  % Diffusivité thermique [m^2/s] //////
        h_conv   ;   % Coefficient de convection [W/m^2·K]

%%%%%%%%%%%%%%% Pouquoi on définie dx et dx ici alors que ça dépend de Lx et Nx ? 
        dx = 0.001    ;     % Pas spatial en x //////
        dy = 0.001    ;     % Pas spatial en y   //////
        dz ;         %epaisseur   //////
        Therm1_loc ; % Localisation thermistance 1 ///////
            Therm1_loc_x ;
            Therm1_loc_y ;
        Therm2_loc ; % Localisation thermistance 2 ////////
            Therm2_loc_x ;
            Therm2_loc_y ;
        Therm3_loc ; % Localisation thermistance 3 //////
            Therm3_loc_x ;
            Therm3_loc_y ;
        pert_loc_x_min ;
        pert_loc_x_max ;
        pert_loc_y_min ;
        pert_loc_y_max ;
        pert_pow   ; % Puissance de la perturbation [W]
        t_pert_deb ; % Temps début perturbation [s]
        t_pert_fin ; % Temps fin perturbation [s]
        dt;    % Pas en temps [s]       //////
        Nt ;  % Nombre d'itérations temporelles /////
        T_piece;
        T_init;
        pin ;
        pin_start_time ; % Le temps de début d'envoie de la puissance [s]
        pin_end_time ; % Le temps de fin d'envoie de la puissance [s]
        couplage_tec = 2.1; % Coefficient de couplage avec le TEC
        pin_loc_x_min ;
        pin_loc_x_max ;
        pin_loc_y_min;
        pin_loc_y_max ;
    end
    
    methods (Access = private)
        
         function ChargerJSONButtonPushedCallback(app, event)
           ChargerJSONButtonPushed(app, event);
       end
        % Callback function for the defaultParameters Button press
        function ParamtrespardfautButtonPushedCallback(app, event)
            ParamtrespardfautButtonPushed(app, event);
        end
                % Callback function for the defaultParameters Button press
        function ChargerparamtresButtonPushedCallback(app, event)
            ChargerparamtresButtonPushed(app, event);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChargerJSONButton
        function ChargerJSONButtonPushed(app, event)
            % Ouvrir une boîte de dialogue pour sélectionner un fichier JSON
            [file, path] = uigetfile('*.json', 'Sélectionnez un fichier JSON');
        
            % Vérifier si l'utilisateur a annulé la sélection
            if isequal(file, 0)
                disp('❌ Sélection annulée.');
                return;
            end
        
            % Construire le chemin complet du fichier sélectionné
            fichierSource = fullfile(path, file);
            
            % Définir le chemin de destination (dossier actuel)
            fichierDestination = fullfile(pwd, 'param.json');
        
            % Copier le fichier dans le dossier actuel sous le nom param.json
            copyfile(fichierSource, fichierDestination);
        end

        % Button pushed function: ParamtrespardfautButton
        function ParamtrespardfautButtonPushed(app, event)
            %% Lire le contenu du fichier JSON
            fid = fopen('default.json', 'r');
            raw = fread(fid, inf, 'uint8=>char')';
            fclose(fid);
                
            %% Décoder le JSON en structure MATLAB
            params = jsondecode(raw);
        
            %% Accéder aux valeurs
            app.TempsTotal =    params.simulation.TempsTotal;     % Durée de la simulation
            app.Lx =            params.geometrie.Lx;       % Longueur [m]
            app.Ly =            params.geometrie.Ly;       % Largeur [m]
            app.epaisseur =     params.geometrie.epaisseur;     % epaisseur[m]
        
            app.Nx =            params.grille.Nx;      % Nombre d'élements en x
            app.Ny =            params.grille.Ny;      % Nombre d'élements en y

%%%%%%%%%% Asteur on calcul dx plutot que de le poser
            app.dx =            app.Lx / app.Nx; %%%%   ////////
            app.dy =            app.Ly / app.Ny; %%%%   ////////
            app.dz =            app.epaisseur; %%%%   ////////
        
            app.k =             params.materiau.k;      % Conductivité Thermique [W/m·K]
            app.rho =           params.materiau.rho;  % Densité [kg/m^3]
            app.cp =            params.materiau.cp;    % Chaleur spécifique  [J/kg·K]
            app.alpha =         app.k/(app.rho*app.cp);    % Diffusivité Thermique [m^2/s] %%%%   ////////
            %app.materiau = params.materiau.nom; % Nom du matériau
            %app.couplage_tec = params.puissance.couplage_TEC; % Coefficient de couplage avec le TEC
        
            %% Paramètres de Convection
            app.h_conv =        params.conditions_limites.h_conv;  % Coeff. de convection[W/m^2·K]
        
            %% Localisation des thermistances
            app.Therm1_loc_x =  params.conditions_initiales.Therm1_loc_x ;   % Localisation de la thermistance sur x [m]
            app.Therm1_loc_y =  params.conditions_initiales.Therm1_loc_y ;   % Localisation de la thermistance sur y [m]
            app.Therm2_loc_x =  params.conditions_initiales.Therm2_loc_x ;   % Localisation de la thermistance sur x [m]
            app.Therm2_loc_y =  params.conditions_initiales.Therm2_loc_y ;   % Localisation de la thermistance sur y [m]
            app.Therm3_loc_x =  params.conditions_initiales.Therm3_loc_x ;   % Localisation de la thermistance sur x [m]
            app.Therm3_loc_y =  params.conditions_initiales.Therm3_loc_y ;   % Localisation de la thermistance sur y [m]
        
            %% Traitement des valeurs de localisation des thermistances (JE CROIS QUE C'EST À RETIRER ÇA)
%%%%%%%%%% Pourquoi on les regroupe ici, dans le Json sont dégroupé et on doit les dégrouper
                 % après pour les utiliser anyways?
            app.Therm1_loc =    [(fix(app.Therm1_loc_x/app.dx) + 1) ,(fix(app.Therm1_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance1) %%%%   ////////
            app.Therm2_loc =    [(fix(app.Therm2_loc_x/app.dx) + 1) ,(fix(app.Therm2_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance2) %%%%   ////////
            app.Therm3_loc =    [(fix(app.Therm3_loc_x/app.dx) + 1) ,(fix(app.Therm3_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance3) %%%%   ////////
         
             %% Paramètres de perturbations 
            app.pert_loc_x_min =    params.pertub.pert_loc_x_min;
            app.pert_loc_x_min =    fix(app.pert_loc_x_min/app.dx)+1; %%%%   ////////
            app.pert_loc_x_max =    params.pertub.pert_loc_x_max;
            app.pert_loc_x_max =    fix(app.pert_loc_x_max/app.dx)+1; %%%%   ////////
            app.pert_loc_y_min =    params.pertub.pert_loc_y_min;
            app.pert_loc_y_min =    fix(app.pert_loc_y_min/app.dy)+1; %%%%   ////////
            app.pert_loc_y_max =    params.pertub.pert_loc_y_max;
            app.pert_loc_y_max =    fix(app.pert_loc_y_max/app.dy)+1; %%%%   ////////
            app.pert_pow =          params.pertub.pert_pow;
            %app.t_pert_deb = params.pertub.t_pert_deb;
            %app.t_pert_fin = params.pertub.t_pert_fin;
            
            %% Paramètres de Puissance
            app.pin =               params.puissance.pin ;  % Puissance [W] (example: 5 W localized input)
            app.pin_start_time =    params.pin_start_time ; % Moment du début de la dissipation calorifique [s]
            app.pin_end_time =      params.pin_end_time ;   % Moment de fin de la dissipation calorifique [s]
        
            app.pin_loc_x_min =     params.puissance.pin_loc_x_min;      %Localisation de la puissance en x - min
            app.pin_loc_x_min =     (fix(app.pin_loc_x_min/app.dx)+ 1); %%%%   ////////
        
            app.pin_loc_x_max =     params.puissance.pin_loc_x_max;      %Localisation de la puissance en x - min
            app.pin_loc_x_max =     (fix(app.pin_loc_x_max/app.dx)+ 1); %%%%   ////////
        
            app.pin_loc_y_min =     params.puissance.pin_loc_y_min;      %Localisation de la puissance en y - min
            app.pin_loc_y_min =     (fix(app.pin_loc_y_min/app.dy) + 1); %%%%   ////////
        
            app.pin_loc_y_max =     params.puissance.pin_loc_y_max;      %Localisation de la puissance en y - max
            app.pin_loc_y_max =     (fix(app.pin_loc_y_max/app.dy) + 1); %%%%   ////////
        
            app.T_piece =           params.conditions_initiales.T_piece ;% Température pièce en Celsius
            app.T_init =            params.conditions_initiales.T_init;

            %% Enregistrement dans le json
            % Charger le fichier JSON
%%%%%% Il faudrait aussi que ça affiche les valeurs pas défaut dans
%%%%%% l'interface quand on les charge
            fid = fopen('param.json', 'r');
            raw = fread(fid, inf, 'uint8=>char')';
            fclose(fid);
            param = jsondecode(raw);
            
            % Modifier les valeurs du dictionnaire avec tes variables
            param.simulation.TempsTotal =       app.TempsTotal;
            param.geometrie.Lx =                app.Lx;
            param.geometrie.Ly =                app.Ly;
            param.geometrie.epaisseur =         app.epaisseur;
            
            param.grille.Nx =                   app.Nx;
            param.grille.Ny =                   app.Ny;
            
            param.materiau.k =                  app.k;
            param.materiau.rho =                app.rho;
            param.materiau.cp =                 app.cp;
            param.conditions_limites.h_conv =   app.h_conv ;
            %param.materiau.nom = app.materiau;
            %param.puissance.couplage_TEC = app.couplage_tec;
            
            param.conditions_initiales.Therm1_loc_x =   app.Therm1_loc_x;
            param.conditions_initiales.Therm1_loc_y =   app.Therm1_loc_y;
            param.conditions_initiales.Therm2_loc_x =   app.Therm2_loc_x;
            param.conditions_initiales.Therm2_loc_y =   app.Therm2_loc_y;
            param.conditions_initiales.Therm3_loc_x =   app.Therm3_loc_x;
            param.conditions_initiales.Therm3_loc_y =   app.Therm3_loc_y;
            param.conditions_initiales.T_piece =        app.T_piece  ;
            param.conditions_initiales.T_init =         app.T_init ;

%%%%% Point à valider, pourquoi on utilise les champs pour les paramètre
%%%%% par défaut ? %%%%%%%%
            param.pertub.pert_loc_x_min =       app.PositionxminxmaxEditField_4.Value;
            param.pertub.pert_loc_x_max =       app.EditField_6.Value;
            param.pertub.pert_loc_y_min =       app.PositionxminxmaxEditField_5.Value;
            param.pertub.pert_loc_y_max =       app.EditField_7.Value;
            param.pertub.pert_pow =             app.pert_pow;
%%%%%%%% Maybe à remettre
            %param.pertub.t_pert_deb = app.t_pert_deb;
            %param.pertub.t_pert_fin = app.t_pert_fin;

            param.puissance.pin =               app.pin ;
%%% Je crois qu'il faut changer comment on attribue les variable ici, genre
%%% utiliser les champs qu'on va ajouter
            param.puissance.pin_start_time =    app.pin_start_time;
            param.puissance.pin_end_time =      app.pin_end_time;
            param.puissance.pin_loc_x_min =     app.PositionxminxmaxEditField_6.Value;
            param.puissance.pin_loc_x_max =     app.EditField_6.Value;
            param.puissance.pin_loc_y_min =     app.PositionxminxmaxEditField_5.Value;
            param.puissance.pin_loc_y_max =     app.EditField_7.Value;
            
            % Enregistrer les modifications dans le fichier JSON
            fid = fopen('param.json', 'w');
            fprintf(fid, '%s', jsonencode(param, 'PrettyPrint', true));
            fclose(fid);
        end

        % Button pushed function: ChargerparamtresButton
        function ChargerparamtresButtonPushed(app, event)
%%%%% Maybe changer le nom pour "appliquer les paramètres"           
            %% Accéder aux valeurs
            app.TempsTotal =    app.DuresEditField.Value ;     % Durée de la simulation
            app.Lx =            app.LongueurmmEditField.Value /1000 ;       % Longueur [m]
            app.Ly =            app.LargeurmmEditField.Value/1000 ;       % Largeur [m]
            app.epaisseur =     app.paisseurmmEditField.Value ;     % epaisseur[m]
%%%% Ici faudrait donner la possibilité au user de changer ça, mettre un
%%%% boutons et non le calculer
            app.Nx =            fix((app.Lx / app.dx))  ;      % Nombre d'élements en x %%%%   ////////&&
            app.Ny =            fix((app.Ly / app.dy)) ;      % Nombre d'élements en y %%%%   ////////&&
    
            app.k =             app.ConductivitWmKEditField.Value;      % Conductivité Thermique [W/m·K]
            app.rho =           app.rhoField.Value;  % Densité [kg/m^3]
            app.cp =            app.cpField.Value ;    % Chaleur spécifique  [J/kg·K]
            app.alpha =         app.k/(app.rho*app.cp);    % Diffusivité Thermique [m^2/s] %%%%   ////////
            %app.materiau =  ; % Nom du matériau
            %app.couplage_tec = ; % Coefficient de couplage avec le TEC
    
            %% Paramètres de Convection
            app.h_conv =        app.hField.Value;  % Coeff. de convection[W/m^2·K]
    
            %% Localisation des thermistances (C'est quoi les app.T1xyEditFeild.value? )
            app.Therm1_loc_x =      app.T1xyEditField.Value ;   % Localisation de la thermistance sur x [m]
            app.Therm1_loc_y =      app.T1xyEditField_2.Value ;   % Localisation de la thermistance sur y [m]
            app.Therm2_loc_x =      app.T2xyEditField.Value ;   % Localisation de la thermistance sur x [m]
            app.Therm2_loc_y =      app.T2xyEditField_4.Value ;   % Localisation de la thermistance sur y [m]
            app.Therm3_loc_x =      app.T3xyEditField.Value ;   % Localisation de la thermistance sur x [m]
            app.Therm3_loc_y =      app.T3xyEditField_2.Value ;   % Localisation de la thermistance sur y [m]
    
            %% Traitement des valeurs de localisation des thermistances
%%%%%% Ici, pourquoi on les rassemble si on les sépare plus tard
            app.Therm1_loc =        [(fix(app.Therm1_loc_x/app.dx) + 1) ,(fix(app.Therm1_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance1)%%%%   ////////
            app.Therm2_loc =        [(fix(app.Therm2_loc_x/app.dx) + 1) ,(fix(app.Therm2_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance2)%%%%   ////////
            app.Therm3_loc =        [(fix(app.Therm3_loc_x/app.dx) + 1) ,(fix(app.Therm3_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance3)%%%%   ////////
    
             %% Paramètres de perturbations 
            app.pert_loc_x_min =    app.PositionxminxmaxEditField_4.Value ;
            app.pert_loc_x_min =    fix(app.pert_loc_x_min/app.dx)+1; %%%%   ////////
            app.pert_loc_x_max =    app.EditField_4.Value;
            app.pert_loc_x_max =    fix(app.pert_loc_x_max/app.dx)+1; %%%%   ////////
            app.pert_loc_y_min =    app.PositionxminxmaxEditField_3.Value;
            app.pert_loc_y_min =    fix(app.pert_loc_y_min/app.dy)+1; %%%%   ////////
            app.pert_loc_y_max =    app.EditField_5.Value;
            app.pert_loc_y_max =    fix(app.pert_loc_y_max/app.dy)+1; %%%%   ////////
            app.pert_pow =          app.TensionEditField.Value; % À réviser  V^2/R
%%%%%%%% Peut-être le remettre (même si on a un bouton perturbation)            
            %app.t_pert_deb = ;
            %app.t_pert_fin = ;
    
    
        %% Paramètres de Puissance
            app.pin =               app.TensionEditField_2.Value * app.couplage_tec ;  %%%%   ////////     % Puissance [W] (example: 5 W localized input)
%%%% J'ai remis le pin start et end time même si on va avoir un boutons 
% Il faut modifier les valeurs qu'on y attribue parce qu'il faut add un
% feild
            app.pin_start_time =    params.pin_start_time ; % Moment du début de la dissipation calorifique [s]
            app.pin_end_time =      params.pin_end_time ;   % Moment de fin de la dissipation calorifique [s]

            app.pin_loc_x_min =     app.PositionxminxmaxEditField_6.Value ; %Localisation de la puissance en x - min
            app.pin_loc_x_min =     (fix(app.pin_loc_x_min/app.dx)+ 1);%%%%   ////////
            app.pin_loc_x_max =     app.EditField_6.Value;                  %Localisation de la puissance en x - min
            app.pin_loc_x_max =     (fix(app.pin_loc_x_max/app.dx)+ 1);%%%%   ////////
        
            app.pin_loc_y_min =     app.PositionxminxmaxEditField_5.Value;  %Localisation de la puissance en y - min
            app.pin_loc_y_min =     (fix(app.pin_loc_y_min/app.dy) + 1);%%%%   ////////
        
            app.pin_loc_y_max =     app.EditField_7.Value;                  %Localisation de la puissance en y - max
            app.pin_loc_y_max =     (fix(app.pin_loc_y_max/app.dy) + 1);%%%%   ////////
        
            app.T_piece =           app.TempratureambianteEditField.Value ;  % Température pièce en Celsius
            app.T_init =            app.TempratureinitialeplaqueEditField.Value; % Température initiale de la plaque
        
    
            %% Enregistrement dans le json
               % Charger le fichier JSON
            fid = fopen('param.json', 'r');
            raw = fread(fid, inf, 'uint8=>char')';
            fclose(fid);
            param = jsondecode(raw);
            
            % Modifier les valeurs du dictionnaire avec tes variables
            param.simulation.TempsTotal =       app.TempsTotal;
            param.geometrie.Lx =                app.Lx;
            param.geometrie.Ly =                app.Ly;
            param.geometrie.epaisseur =         app.epaisseur;
            
            param.grille.Nx =                   app.Nx;
            param.grille.Ny =                   app.Ny;
            
            param.materiau.k =                  app.k;
            param.materiau.rho =                app.rho;
            param.materiau.cp =                 app.cp;
            param.conditions_limites.h_conv =   app.h_conv ;
            %param.materiau.nom = app.materiau;
            
            %param.puissance.couplage_TEC = app.couplage_tec;
            
            param.conditions_initiales.Therm1_loc_x =   app.Therm1_loc_x;
            param.conditions_initiales.Therm1_loc_y =   app.Therm1_loc_y;
            param.conditions_initiales.Therm2_loc_x =   app.Therm2_loc_x;
            param.conditions_initiales.Therm2_loc_y =   app.Therm2_loc_y;
            param.conditions_initiales.Therm3_loc_x =   app.Therm3_loc_x;
            param.conditions_initiales.Therm3_loc_y =   app.Therm3_loc_y;
            param.conditions_initiales.T_piece =        app.T_piece  ;
            param.conditions_initiales.T_init =         app.T_init ;
            
            param.pertub.pert_loc_x_min =               app.PositionxminxmaxEditField_4.Value;
            param.pertub.pert_loc_x_max =               app.EditField_6.Value;
            param.pertub.pert_loc_y_min =               app.PositionxminxmaxEditField_5.Value;
            param.pertub.pert_loc_y_max =               app.EditField_7.Value;
            param.pertub.pert_pow =                     app.pert_pow;
%%% Ajuster si on décide de remmetre ces params là
            %param.pertub.t_pert_deb = app.t_pert_deb;
            %param.pertub.t_pert_fin = app.t_pert_fin;
            param.puissance.pin =                       app.pin ;
            param.pin_start_time =                      app.pin_start_time ;
            param.pin_end_time =                        app.pin_end_time ;
            param.puissance.pin_loc_x_min =             app.PositionxminxmaxEditField_6.Value;
            param.puissance.pin_loc_x_max =             app.EditField_6.Value;
            param.puissance.pin_loc_y_min =             app.PositionxminxmaxEditField_5.Value;
            param.puissance.pin_loc_y_max =             app.EditField_7.Value;
            
            % Enregistrer les modifications dans le fichier JSON
            fid = fopen('param.json', 'w');
            fprintf(fid, '%s', jsonencode(param, 'PrettyPrint', true));
            fclose(fid);
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)

            %%% Faire l'assignation de toutes les variable nécessaire pour
            %%% la simulation (on fait ça pour pas que ça ait besoin
            %%% d'accéder tjrs à app."la_variable_qu'on_veut
            global run_sim;
            %% Valider que le pool est innitié
            pool = gcp('nocreate');
            if isempty(pool)
                    pool = parpool('local', 1);
            end 
            %% Initialiser les variables
            TempsTotal         = app.TempsTotal;
            Lx                 = app.Lx;
            Ly                 = app.Ly;
            epaisseur          = app.epaisseur;
            
            Nx                 = app.Nx;
            Ny                 = app.Ny;
            dx                 = Lx / Nx;
            dy                 = Ly / Ny;

            k                  = app.k;
            rho                = app.rho;
            cp                 = app.cp;
            couplage_tec       = app.couplage_TEC;
            
            dz                 = epaisseur;
            
            h_conv             = app.h_conv;
            pin                = app.pin;
            % Serait pertinent de le réintégrer
            pin_start_time     = app.pin_start_time;
            
            % serait pertinent de le ré-intégrer
            pin_end_time       = app.pin_end_time;
            if isnan(Pin_end_time)
               Pin_end_time = TempsTotal + 1;
            end
            % à valider ces variable là
            pin_loc_x_min       = fix(app.pin_loc_x_min/ dx) + 1;
            pin_loc_x_max       = fix(app.pin_loc_x_max/ dx) + 1;
            pin_loc_y_min       = fix(app.pin_loc_y_min/ dy) + 1;
            pin_loc_y_max       = fix(app.pin_loc_y_max/ dy) + 1;
            
            T_piece             = app.T_piece;
            T_init              = app.T_init;
            
            Therm1_loc_x        = fix(app.Therm1_loc_x/ dx) + 1;
            Therm1_loc_y        = fix(app.Therm1_loc_y/ dy) + 1;
            Therm2_loc_x        = fix(app.Therm2_loc_x/ dx) + 1;
            Therm2_loc_y        = fix(app.Therm2_loc_y/ dy) + 1;
            Therm3_loc_x        = fix(app.Therm3_loc_x/ dx) + 1;
            Therm3_loc_y        = fix(app.Therm3_loc_y/ dy) + 1;
            
            pert_loc_x_min      = fix(app.pert_loc_x_min/ dx) + 1;
            pert_loc_x_max      = fix(app.pert_loc_x_max/ dx) + 1;
            pert_loc_y_min      = fix(app.pert_loc_y_min / dy) + 1;
            pert_loc_y_max      = fix(app.pert_loc_y_max / dy) + 1;
            pert_pow            = app.pert_pow;
            t_pert_deb          = app.t_pert_deb;
            t_pert_fin          = app.t_pert_fin;
            %% Calcul préalable
            alpha               = k / (rho * cp);
            % dx et dy calculé plus haut
            dt                  = (1/(4*alpha)) * (dx^2 * dy^2) / (dx^2 + dy^2);
            Nt                  = round(TempsTotal / dt);

                % Conection
            aire_sides_y        = dy * dz;      
            aire_sides_x        = dx * dz;
            aire_top            = dx * dy;
            volume              = dx * dy * dz;
            
            Temps               = (0:Nt-1) * dt;
            x                   = ((1:Nx) - 0.5) .* dx;
            y                   = ((1:Ny) - 0.5) .* dy;
            [Y, X]              = meshgrid(y, x);

                % Puissance
            P                   = zeros(Nx,Ny);
            nb_elts_pin         = (Pin_loc_y_max - Pin_loc_y_min + 1) * (Pin_loc_x_max - Pin_loc_x_min + 1);
            P(Pin_loc_x_min:Pin_loc_x_max, Pin_loc_y_min:Pin_loc_y_max) = Pin / nb_elts_pin;
            
                % Conditions initiales
            T_piece             = 273.15 + T_piece;  % conversion en Kelvin
            T_init              = 273.15 + T_init;  % conversion en Kelvin
            T                   = T_init .* ones(Nx, Ny);

            dt_dx2              = (alpha * dt) / dx^2;
            dt_dy2              = (alpha * dt) / dy^2;
            conv_term_top       = (2 * aire_top * h_conv * dt) / (volume * rho * cp);
            conv_term_sides_y   = (aire_sides_y * h_conv * dt) / (volume * rho * cp);
            conv_term_sides_x   = (aire_sides_x * h_conv * dt) / (volume * rho * cp);
            power_term          = dt / (rho * cp * volume);
            deposited           = 0;
            %% Affection nécessaire pour faire les calcul en parallèles
            run_sim = "jeanpaul";
            function calcul_sim(T0, Nt_local, snapshotPeriod_local, dq_local)
                T = T0;
                % Tableaux locaux pour mesurer (pour le worker)
                localTherm1 = zeros(1, Nt_local);
                localTherm2 = zeros(1, Nt_local);
                localTherm3 = zeros(1, Nt_local);
                localEnergyAdded = zeros(1, Nt_local);
                localEnergyLoss = zeros(1, Nt_local);
                
                for t = 1:Nt_local
                    % Calcul de Tnew par schéma numérique (inchangé)
                    Tnew = T;
                    Tnew(2:Nx-1, 2:Ny-1) = T(2:Nx-1, 2:Ny-1) + dt_dx2*(T(1:Nx-2, 2:Ny-1)-2*T(2:Nx-1, 2:Ny-1)+T(3:Nx, 2:Ny-1)) + dt_dy2*(T(2:Nx-1, 1:Ny-2)-2*T(2:Nx-1, 2:Ny-1)+T(2:Nx-1, 3:Ny));
                    Tnew(1, 2:Ny-1) = T(1, 2:Ny-1) + dt_dx2*(T(2, 2:Ny-1)-T(1, 2:Ny-1)) + dt_dy2*(T(1, 1:Ny-2)-2*T(1, 2:Ny-1)+T(1, 3:Ny));
                    Tnew(Nx, 2:Ny-1) = T(Nx, 2:Ny-1) + dt_dx2*(T(Nx-1, 2:Ny-1)-T(Nx, 2:Ny-1)) + dt_dy2*(T(Nx, 1:Ny-2)-2*T(Nx, 2:Ny-1)+T(Nx, 3:Ny));
                    Tnew(2:Nx-1, 1) = T(2:Nx-1, 1) + dt_dy2*(T(1:Nx-2, 1)-2*T(2:Nx-1, 1)+T(3:Nx, 1)) + dt_dx2*(T(2:Nx-1, 2)-T(2:Nx-1, 1));
                    Tnew(2:Nx-1, Ny) = T(2:Nx-1, Ny) + dt_dy2*(T(1:Nx-2, Ny)-2*T(2:Nx-1, Ny)+T(3:Nx, Ny)) + dt_dx2*(T(2:Nx-1, Ny-1)-T(2:Nx-1, Ny));
                    Tnew(1, 1) = T(1, 1) + dt_dx2*(T(2, 1)-T(1, 1)) + dt_dy2*(T(1, 2)-T(1, 1));
                    Tnew(1, Ny) = T(1, Ny) + dt_dx2*(T(2, Ny)-T(1, Ny)) + dt_dy2*(T(1, Ny-1)-T(1, Ny));
                    Tnew(Nx, 1) = T(Nx, 1) + dt_dx2*(T(Nx-1, 1)-T( Nx, 1)) + dt_dy2*(T(Nx, 2)-T(Nx, 1));
                    Tnew(Nx, Ny) = T(Nx, Ny) + dt_dx2*(T(Nx-1, Ny)-T(Nx, Ny)) + dt_dy2*(T(Nx, Ny-1)-T(Nx, Ny));
                    Tnew(1:Nx,1:Ny) = Tnew(1:Nx,1:Ny) - conv_term_top .* (T(1:Nx,1:Ny)-T_piece);
                    Tnew(1, :) = Tnew(1, :) - conv_term_sides_y .* (T(1, :) - T_piece);
                    Tnew(Nx, :) = Tnew(Nx, :) - conv_term_sides_y .* (T(Nx, :) - T_piece);
                    Tnew(:, 1) = Tnew(:, 1) - conv_term_sides_x .* (T(:, 1) - T_piece);
                    Tnew(:, Ny) = Tnew(:, Ny) - conv_term_sides_x .* (T(:, Ny) - T_piece);
                    
                    if pert_pow ~= 0 && deposited == 0 
                        if (round(t_pert_deb/dt) == t)
                            nb_elts_pert = (pert_loc_y_max-pert_loc_y_min+1)*(pert_loc_x_max-pert_loc_x_min+1);
                            ajout = (pert_pow/nb_elts_pert);
                            P(pert_loc_x_min:pert_loc_x_max, pert_loc_y_min:pert_loc_y_max) = P(pert_loc_x_min:pert_loc_x_max, pert_loc_y_min:pert_loc_y_max) + ajout;
                            deposited = deposited + 1;
                        end
                    end
                    if pert_pow ~= 0 && deposited == 1
                        if (t == round(t_pert_fin/dt))
                            P(pert_loc_x_min:pert_loc_x_max, pert_loc_y_min:pert_loc_y_max) = P(pert_loc_x_min:pert_loc_x_max, pert_loc_y_min:pert_loc_y_max) - ajout;
                            deposited = deposited + 1;
                        end 
                    end
                    Tnew = Tnew + power_term .* P;
        
                    energy_added(t) = sum(P(:)) * dt;
                    energy_loss_sides_ligne_1 = (h_conv*aire_sides_y*dt)* sum(T(1, 1:Ny)-T_piece);
                    energy_loss_sides_ligne_f = (h_conv*aire_sides_y*dt)*sum(T(Nx, 1:Ny)-T_piece);
                    energy_loss_top_down = (h_conv*2*aire_top*dt)*sum(sum(T(1:Nx, 1:Ny)-T_piece));
                    energy_loss_sides_colonne_1 = (h_conv*aire_sides_x*dt)* sum(T(1:Nx, 1)-T_piece);
                    energy_loss_sides_colonne_f = (h_conv*aire_sides_x*dt)* sum(T(1:Nx, Ny)-T_piece);
                    energy_loss(t) = energy_loss_sides_ligne_1 + energy_loss_sides_ligne_f + energy_loss_top_down + energy_loss_sides_colonne_1 + energy_loss_sides_colonne_f;
                    
                    T = Tnew;
                    localTherm1(t) = T(Therm1_loc_x, Therm1_loc_y);
                    localTherm2(t) = T(Therm2_loc_x, Therm2_loc_y);
                    localTherm3(t) = T(Therm3_loc_x, Therm3_loc_y);
                    localEnergyAdded(t) = sum(P(:)) * dt;
                    localEnergyLoss(t) = energy_loss_sides_ligne_1 + energy_loss_sides_ligne_f + energy_loss_top_down + energy_loss_sides_colonne_1 + energy_loss_sides_colonne_f;
                    
                    if mod(t, snapshotPeriod_local) == 0 || t == 1
                        dataOut.snapshot = T;
                        dataOut.t = t;
                        dataOut.therm1 = localTherm1(t);
                        dataOut.therm2 = localTherm2(t);
                        dataOut.therm3 = localTherm3(t);
                        dataOut.energyAdded = localEnergyAdded(t);
                        dataOut.energyLoss = localEnergyLoss(t);
                        send(dq_local, dataOut);
                        check_if_pause()
                    end
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%
            %% Initialiser le pool, attitrer à la fonction de calcul, initialiser une dataqueue qui appel une fonction
            % d'update de l'affichage. On faudrait d'ailleurs précréer les
            % figure
        end

        % Value changed function: T2xyEditField_4
        function startupFcn(app, event)
            value = app.T2xyEditField_4.Value;
                pool = gcp('nocreate');
            if isempty(pool)
                pool = parpool('local', 1);
            end
            ParamtrespardfautButtonPushed(app, []);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ThermoProUIFigure = uifigure('Visible', 'off');
            app.ThermoProUIFigure.Name = 'ThermoPro';
            app.GridLayout = uigridlayout(app.ThermoProUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Température aux thermistances🌡')
            xlabel(app.UIAxes, 'Temps [s]')
            ylabel(app.UIAxes, 'Température [°C]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Distribution de la température sur la plaque🌡')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LongueurmmEditFieldLabel = uilabel(app.GridLayout);
            app.LongueurmmEditFieldLabel.Text = 'Longueur [mm]';
            app.LongueurmmEditField = uieditfield(app.GridLayout, 'numeric');
            app.paisseurmmEditFieldLabel = uilabel(app.GridLayout);
            app.paisseurmmEditFieldLabel.Text = 'Épaisseur [mm]';
            app.paisseurmmEditField = uieditfield(app.GridLayout, 'numeric');
            app.CapacitCalorifiqueJkgKEditFieldLabel = uilabel(app.GridLayout);
            app.CapacitCalorifiqueJkgKEditFieldLabel.Text = 'Capacité Calorifique [J/kg·K]';
            app.cpField = uieditfield(app.GridLayout, 'numeric');
            app.CoefficientdeConvectionWm2KEditFieldLabel = uilabel(app.GridLayout);
            app.CoefficientdeConvectionWm2KEditFieldLabel.Text = 'Coefficient de Convection [W/(m^2·K]';
            app.hField = uieditfield(app.GridLayout, 'numeric');
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.TempratureinitialeCEditFieldLabel = uilabel(app.GridLayout);
            app.TempratureinitialeCEditFieldLabel.Text = 'Température initiale [°C]';
            app.TempratureinitialeCEditField = uieditfield(app.GridLayout, 'numeric');
            app.LargeurmmEditField = uieditfield(app.GridLayout, 'numeric');
            app.TensionauTECPanel = uipanel(app.GridLayout);
            app.TensionauTECPanel.Title = 'Tension au TEC';
            app.PositionxminxmaxEditField_2Label_3 = uilabel(app.TensionauTECPanel);
            app.PositionxminxmaxEditField_2Label_3.Text = 'Position [ymin, ymax]';
            app.EditField_6 = uieditfield(app.TensionauTECPanel, 'numeric');
            app.PositionxminxmaxEditField_5 = uieditfield(app.TensionauTECPanel, 'numeric');
            app.EditField_7 = uieditfield(app.TensionauTECPanel, 'numeric');
            app.TensionEditField_2Label = uilabel(app.TensionauTECPanel);
            app.TensionEditField_2Label.Text = 'Tension';
            app.TensionEditField_2 = uieditfield(app.TensionauTECPanel, 'numeric');
            app.PositionxminxmaxEditField_6Label = uilabel(app.TensionauTECPanel);
            app.PositionxminxmaxEditField_6Label.Text = 'Position [xmin, xmax]';
            app.PositionxminxmaxEditField_6 = uieditfield(app.TensionauTECPanel, 'numeric');
            app.Switch_2 = uiswitch(app.TensionauTECPanel, 'slider');
            app.PerturbationPanel = uipanel(app.GridLayout);
            app.PerturbationPanel.Title = 'Perturbation';
            app.TensionEditFieldLabel = uilabel(app.PerturbationPanel);
            app.TensionEditFieldLabel.Text = 'Tension';
            app.TensionEditField = uieditfield(app.PerturbationPanel, 'numeric');
            app.PositionxminxmaxEditField_2Label_2 = uilabel(app.PerturbationPanel);
            app.PositionxminxmaxEditField_2Label_2.Text = 'Position [ymin, ymax]';
            app.EditField_4 = uieditfield(app.PerturbationPanel, 'numeric');
            app.PositionxminxmaxEditField_3 = uieditfield(app.PerturbationPanel, 'numeric');
            app.EditField_5 = uieditfield(app.PerturbationPanel, 'numeric');
            app.PositionxminxmaxEditField_4Label = uilabel(app.PerturbationPanel);
            app.PositionxminxmaxEditField_4Label.Text = 'Position [xmin, xmax]';
            app.PositionxminxmaxEditField_4 = uieditfield(app.PerturbationPanel, 'numeric');
            app.Switch = uiswitch(app.PerturbationPanel, 'slider');
            app.startButton = uibutton(app.GridLayout, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'Démarrer ▶ ';
            app.LargeurmmEditFieldLabel = uilabel(app.GridLayout);
            app.LargeurmmEditFieldLabel.Text = 'Largeur [mm]';
            app.stopButton = uibutton(app.GridLayout, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'Arrêter ⬛';
            app.MasseVolumiquegcm3EditFieldLabel = uilabel(app.GridLayout);
            app.MasseVolumiquegcm3EditFieldLabel.Text = 'Masse Volumique [g/cm^3]';
            app.rhoField = uieditfield(app.GridLayout, 'numeric');
            app.ConductivitWmKEditFieldLabel = uilabel(app.GridLayout);
            app.ConductivitWmKEditFieldLabel.Text = 'Conductivité [W/m·K]';
            app.ConductivitWmKEditField = uieditfield(app.GridLayout, 'numeric');
            app.DuresEditFieldLabel = uilabel(app.GridLayout);
            app.DuresEditFieldLabel.Text = 'Durée [s]';
            app.DuresEditField = uieditfield(app.GridLayout, 'numeric');
            app.ChargerparamtresButton = uibutton(app.GridLayout, 'push');
            app.ChargerparamtresButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerparamtresButtonPushed, true);
            app.ChargerparamtresButton.Text = ' Charger paramètres ';
            app.ParamtrespardfautButton = uibutton(app.GridLayout, 'push');
            app.ParamtrespardfautButton.ButtonPushedFcn = createCallbackFcn(app, @ParamtrespardfautButtonPushed, true);
            app.ParamtrespardfautButton.Text = 'Paramètres par défaut ';
            app.TempratureinitialeplaqueEditFieldLabel = uilabel(app.GridLayout);
            app.TempratureinitialeplaqueEditFieldLabel.Text = 'Température initiale plaque';
            app.TempratureinitialeplaqueEditField = uieditfield(app.GridLayout, 'numeric');
            app.TempratureambianteEditFieldLabel = uilabel(app.GridLayout);
            app.TempratureambianteEditFieldLabel.Text = 'Température ambiante';
            app.TempratureambianteEditField = uieditfield(app.GridLayout, 'numeric');
            app.LocalisationdesthermistancesPanel = uipanel(app.GridLayout);
            app.LocalisationdesthermistancesPanel.Title = 'Localisation des thermistances';
            app.T1xyEditFieldLabel = uilabel(app.LocalisationdesthermistancesPanel);
            app.T1xyEditFieldLabel.Text = 'T1 [x, y]';
            app.T1xyEditField = uieditfield(app.LocalisationdesthermistancesPanel, 'numeric');
            app.T2xyEditFieldLabel = uilabel(app.LocalisationdesthermistancesPanel);
            app.T2xyEditFieldLabel.Text = 'T2 [x, y]';
            app.T2xyEditField = uieditfield(app.LocalisationdesthermistancesPanel, 'numeric');
            app.T3xyEditFieldLabel = uilabel(app.LocalisationdesthermistancesPanel);
            app.T3xyEditFieldLabel.Text = 'T3 [x, y]';
            app.T3xyEditField = uieditfield(app.LocalisationdesthermistancesPanel, 'numeric');
            app.T1xyEditField_2 = uieditfield(app.LocalisationdesthermistancesPanel, 'numeric');
            app.T2xyEditField_4 = uieditfield(app.LocalisationdesthermistancesPanel, 'numeric');
            app.T2xyEditField_4.ValueChangedFcn = createCallbackFcn(app, @startupFcn, true);
            app.T3xyEditField_2 = uieditfield(app.LocalisationdesthermistancesPanel, 'numeric');
            app.ChargerJSONButton = uibutton(app.GridLayout, 'push');
            app.ChargerJSONButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerJSONButtonPushed, true);
            app.ChargerJSONButton.Text = '📤 Charger .JSON';
            app.PauseButton = uibutton(app.GridLayout, 'push');
            app.PauseButton.Text = 'Pause ❚❚';
            % Show the figure after all components are created
            app.ThermoProUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThermoProUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThermoProUIFigure)
        end
    end
end